classdef CommunicationWithMatlab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CommunicationLabUIFigurre  matlab.ui.Figure
        LEDButtonGroup             matlab.ui.container.ButtonGroup
        OFFButton                  matlab.ui.control.ToggleButton
        ONButton                   matlab.ui.control.ToggleButton
        ButtonCounterLabel         matlab.ui.control.Label
        CountLabel                 matlab.ui.control.Label
        ServoSliderLabel           matlab.ui.control.Label
        ServoSlider                matlab.ui.control.Slider
        ServoValueEditFieldLabel   matlab.ui.control.Label
        ServoValueEditField        matlab.ui.control.NumericEditField
        SendServoValueButton       matlab.ui.control.Button
        Image                      matlab.ui.control.Image
    end

    
    properties (Access = private)
        counter=0;
        serialObj % Description
    end
    
    
    methods (Access = private)
        function sendServoValue(app,degrees)
            %degrees is a number (maybe an int, maybe a double
            degrees=round(degrees);
            command=sprintf("SERVO %d", degrees);
            
            fprintf("Sending: %s\n", command);
            writeline(app.serialObj,command); %causes servo to move
        end
        
        function serialObjCallback(app, serialObj,eventData)
            message = strtrim(readline(serialObj)) %command window recieves feedback
            %No Parameters
            if strcmpi(message,"B")
                app.counter=app.counter+1;
                app.CountLabel.Text=sprintf("Count=%d", app.counter);
            end
            % Paramters
            messageArray=split(message);
            if strcmpi(messageArray(1), "X")
                xValue=str2double(messageArray(2));
                app.Image.Position(1)=xValue/2;
            end
            if strcmpi(messageArray(1), "Y")
                yValue=str2double(messageArray(2));
                app.Image.Position(2)=yValue/2;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.serialObj=serialport("COM4",9600);
            flush(app.serialObj);
            configureCallback(app.serialObj,"terminator",@app.serialObjCallback);


        end

        % Selection changed function: LEDButtonGroup
        function LEDButtonGroupSelectionChanged(app, event)
            selectedButton = app.LEDButtonGroup.SelectedObject;
            
            if selectedButton==app.OFFButton
                writeline(app.serialObj, "LED OFF");
            end
            if selectedButton==app.ONButton
                writeline(app.serialObj,"LED ON");
                
            end
        end

        % Value changed function: ServoSlider
        function ServoSliderValueChanged(app, event)
            value = app.ServoSlider.Value;
            app.sendServoValue(value);
        end

        % Value changing function: ServoSlider
        function ServoSliderValueChanging(app, event)
            changingValue = event.Value; %updates as you go
            app.sendServoValue(changingValue);
        end

        % Button pushed function: SendServoValueButton
        function SendServoValueButtonPushed(app, event)
            value=(app.ServoValueEditField.Value);
            app.sendServoValue((value));
            
            app.ServoSlider.Value=value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CommunicationLabUIFigurre = uifigure('Visible', 'off');
            app.CommunicationLabUIFigurre.Name = 'MATLAB App';
            app.LEDButtonGroup = uibuttongroup(app.CommunicationLabUIFigurre);
            app.LEDButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LEDButtonGroupSelectionChanged, true);
            app.LEDButtonGroup.Title = 'LED';
            app.OFFButton = uitogglebutton(app.LEDButtonGroup);
            app.OFFButton.Text = 'OFF';
            app.OFFButton.Value = true;
            app.ONButton = uitogglebutton(app.LEDButtonGroup);
            app.ONButton.Text = 'ON';
            app.ButtonCounterLabel = uilabel(app.CommunicationLabUIFigurre);
            app.ButtonCounterLabel.Text = 'Button Counter:';
            app.CountLabel = uilabel(app.CommunicationLabUIFigurre);
            app.CountLabel.Text = 'Count=0';
            app.ServoSliderLabel = uilabel(app.CommunicationLabUIFigurre);
            app.ServoSliderLabel.Text = {'Servo '; 'Slider'};
            app.ServoSlider = uislider(app.CommunicationLabUIFigurre);
            app.ServoSlider.Limits = [0 180];
            app.ServoSlider.ValueChangedFcn = createCallbackFcn(app, @ServoSliderValueChanged, true);
            app.ServoSlider.ValueChangingFcn = createCallbackFcn(app, @ServoSliderValueChanging, true);
            app.ServoValueEditFieldLabel = uilabel(app.CommunicationLabUIFigurre);
            app.ServoValueEditFieldLabel.Text = {'Servo '; 'Value'};
            app.ServoValueEditField = uieditfield(app.CommunicationLabUIFigurre, 'numeric');
            app.SendServoValueButton = uibutton(app.CommunicationLabUIFigurre, 'push');
            app.SendServoValueButton.ButtonPushedFcn = createCallbackFcn(app, @SendServoValueButtonPushed, true);
            app.SendServoValueButton.Text = 'Send Servo Value';
            app.Image = uiimage(app.CommunicationLabUIFigurre);
            app.Image.ImageSource = 'ship.png';
            % Show the figure after all components are created
            app.CommunicationLabUIFigurre.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CommunicationWithMatlab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CommunicationLabUIFigurre)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CommunicationLabUIFigurre)
        end
    end
end